classdef VisualizacionDatos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DiagramadeDispersionTab       matlab.ui.container.Tab
        PetalWidhtCheckBox            matlab.ui.control.CheckBox
        SepalWidhtCheckBox            matlab.ui.control.CheckBox
        PetalLenghtCheckBox           matlab.ui.control.CheckBox
        SepalLenghtCheckBox           matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
        MedidasdeTendenciaCentralTab  matlab.ui.container.Tab
        HistogramaTab                 matlab.ui.container.Tab
        DiagramadeCajayBigotesTab     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        listaSeleccionados = {};

        A = {5.1, 4.9, 4.7, 4.6, 5, 5.4, 4.6, 5, 4.4, 4.9, 5.4, 4.8, 4.8, 4.3, 5.8, 5.7, 5.4, 5.1, 5.7, 5.1, 5.4, 5.1, 4.6, 5.1, 4.8, 5, 5, 5.2, 5.2, 4.7, 4.8, 5.4, 5.2, 5.5, 4.9, 5, 5.5, 4.9, 4.4, 5.1, 5, 4.5, 4.4, 5, 5.1, 4.8, 5.1, 4.6, 5.3, 5, 7, 6.4, 6.9, 5.5, 6.5, 5.7, 6.3, 4.9, 6.6, 5.2, 5, 5.9, 6, 6.1, 5.6, 6.7, 5.6, 5.8, 6.2, 5.6, 5.9, 6.1, 6.3, 6.1, 6.4, 6.6, 6.8, 6.7, 6, 5.7, 5.5, 5.5, 5.8, 6, 5.4, 6, 6.7, 6.3, 5.6, 5.5, 5.5, 6.1, 5.8, 5, 5.6, 5.7, 5.7, 6.2, 5.1, 5.7, 6.3, 5.8, 7.1, 6.3, 6.5, 7.6, 4.9, 7.3, 6.7, 7.2, 6.5, 6.4, 6.8, 5.7, 5.8, 6.4, 6.5, 7.7, 7.7, 6, 6.9, 5.6, 7.7, 6.3, 6.7, 7.2, 6.2, 6.1, 6.4, 7.2, 7.4, 7.9, 6.4, 6.3, 6.1, 7.7, 6.3, 6.4, 6, 6.9, 6.7, 6.9, 5.8, 6.8, 6.7, 6.7, 6.3, 6.5, 6.2, 5.9};

        B = {3.5, 3, 3.2, 3.1, 3.6, 3.9, 3.4, 3.4, 2.9, 3.1, 3.7, 3.4, 3, 3, 4, 4.4, 3.9, 3.5, 3.8, 3.8, 3.4, 3.7, 3.6, 3.3, 3.4, 3, 3.4, 3.5, 3.4, 3.2, 3.1, 3.4, 4.1, 4.2, 3.1, 3.2, 3.5, 3.6, 3, 3.4, 3.5, 2.3, 3.2, 3.5, 3.8, 3, 3.8, 3.2, 3.7, 3.3, 3.2, 3.2, 3.1, 2.3, 2.8, 2.8, 3.3, 2.4, 2.9, 2.7, 2, 3, 2.2, 2.9, 2.9, 3.1, 3, 2.7, 2.2, 2.5, 3.2, 2.8, 2.5, 2.8, 2.9, 3, 2.8, 3, 2.9, 2.6, 2.4, 2.4, 2.7, 2.7, 3, 3.4, 3.1, 2.3, 3, 2.5, 2.6, 3, 2.6, 2.3, 2.7, 3, 2.9, 2.9, 2.5, 2.8, 3.3, 2.7, 3, 2.9, 3, 3, 2.5, 2.9, 2.5, 3.6, 3.2, 2.7, 3, 2.5, 2.8, 3.2, 3, 3.8, 2.6, 2.2, 3.2, 2.8, 2.8, 2.7, 3.3, 3.2, 2.8, 3, 2.8, 3, 2.8, 3.8, 2.8, 2.8, 2.6, 3, 3.4, 3.1, 3, 3.1, 3.1, 3.1, 2.7, 3.2, 3.3, 3, 2.5, 3, 3.4, 3};

        C = {1.4, 1.4, 1.3, 1.5, 1.4, 1.7, 1.4, 1.5, 1.4, 1.5, 1.5, 1.6, 1.4, 1.1, 1.2, 1.5, 1.3, 1.4, 1.7, 1.5, 1.7, 1.5, 1, 1.7, 1.9, 1.6, 1.6, 1.5, 1.4, 1.6, 1.6, 1.5, 1.5, 1.4, 1.5, 1.2, 1.3, 1.4, 1.3, 1.5, 1.3, 1.3, 1.3, 1.6, 1.9, 1.4, 1.6, 1.4, 1.5, 1.4, 4.7, 4.5, 4.9, 4, 4.6, 4.5, 4.7, 3.3, 4.6, 3.9, 3.5, 4.2, 4, 4.7, 3.6, 4.4, 4.5, 4.1, 4.5, 3.9, 4.8, 4, 4.9, 4.7, 4.3, 4.4, 4.8, 5, 4.5, 3.5, 3.8, 3.7, 3.9, 5.1, 4.5, 4.5, 4.7, 4.4, 4.1, 4, 4.4, 4.6, 4, 3.3, 4.2, 4.2, 4.2, 4.3, 3, 4.1, 6, 5.1, 5.9, 5.6, 5.8, 6.6, 4.5, 6.3, 5.8, 6.1, 5.1, 5.3, 5.5, 5, 5.1, 5.3, 5.5, 6.7, 6.9, 5, 5.7, 4.9, 6.7, 4.9, 5.7, 6, 4.8, 4.9, 5.6, 5.8, 6.1, 6.4, 5.6, 5.1, 5.6, 6.1, 5.6, 5.5, 4.8, 5.4, 5.6, 5.1, 5.1, 5.9, 5.7, 5.2, 5, 5.2, 5.4, 5.1};

        D = {0.2, 0.2, 0.2, 0.2, 0.2, 0.4, 0.3, 0.2, 0.2, 0.1, 0.2, 0.2, 0.1, 0.1, 0.2, 0.4, 0.4, 0.3, 0.3, 0.3, 0.2, 0.4, 0.2, 0.5, 0.2, 0.2, 0.4, 0.2, 0.2, 0.2, 0.2, 0.4, 0.1, 0.2, 0.2, 0.2, 0.2, 0.1, 0.2, 0.2, 0.3, 0.3, 0.2, 0.6, 0.4, 0.3, 0.2, 0.2, 0.2, 0.2, 1.4, 1.5, 1.5, 1.3, 1.5, 1.3, 1.6, 1, 1.3, 1.4, 1, 1.5, 1, 1.4, 1.3, 1.4, 1.5, 1, 1.5, 1.1, 1.8, 1.3, 1.5, 1.2, 1.3, 1.4, 1.4, 1.7, 1.5, 1, 1.1, 1, 1.2, 1.6, 1.5, 1.6, 1.5, 1.3, 1.3, 1.3, 1.2, 1.4, 1.2, 1, 1.3, 1.2, 1.3, 1.3, 1.1, 1.3, 2.5, 1.9, 2.1, 1.8, 2.2, 2.1, 1.7, 1.8, 1.8, 2.5, 2, 1.9, 2.1, 2, 2.4, 2.3, 1.8, 2.2, 2.3, 1.5, 2.3, 2, 2, 1.8, 2.1, 1.8, 1.8, 1.8, 2.1, 1.6, 1.9, 2, 2.2, 1.5, 1.4, 2.3, 2.4, 1.8, 1.8, 2.1, 2.4, 2.3, 1.9, 2.3, 2.5, 2.3, 1.9, 2, 2.3, 1.8};
    end
    
    methods (Access = private)
        
        function checkBoxDispersion(app, variable, estado)

            switch variable
                case 'sepalWidht'
                    seleccionado = 'A'; %Se agrega 'Sepal Width'
                case 'sepalLenght'
                    seleccionado = 'B'; %Se agrega 'Sepal Lenght' 
                case 'petalWidht'
                    seleccionado = 'C'; %Se agrega 'Petal Width' 
                case 'petalLenght'
                    seleccionado = 'D'; %Se agrega 'Petal Lenght' 
            end

            if estado == "active"
                app.listaSeleccionados{end + 1} = seleccionado;
            elseif estado == "blank"
                indiceEliminar = find(strcmp(app.listaSeleccionados, seleccionado));
                app.listaSeleccionados(indiceEliminar) = []; 
            end

            if numel(app.listaSeleccionados) == 2
                
                %algo
            end
        end
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DiagramadeDispersionTab = uitab(app.TabGroup);
            app.DiagramadeDispersionTab.Title = 'Diagrama de Dispersión';
            app.UIAxes = uiaxes(app.DiagramadeDispersionTab);
            title(app.UIAxes, 'Gráfica de dispersión')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SepalLenghtCheckBox = uicheckbox(app.DiagramadeDispersionTab);
            app.SepalLenghtCheckBox.Text = 'Sepal Lenght';
            app.PetalLenghtCheckBox = uicheckbox(app.DiagramadeDispersionTab);
            app.PetalLenghtCheckBox.Text = 'Petal Lenght';
            app.SepalWidhtCheckBox = uicheckbox(app.DiagramadeDispersionTab);
            app.SepalWidhtCheckBox.Text = 'Sepal Widht';
            app.PetalWidhtCheckBox = uicheckbox(app.DiagramadeDispersionTab);
            app.PetalWidhtCheckBox.Text = 'Petal Widht';
            app.MedidasdeTendenciaCentralTab = uitab(app.TabGroup);
            app.MedidasdeTendenciaCentralTab.Title = 'Medidas de Tendencia Central';
            app.HistogramaTab = uitab(app.TabGroup);
            app.HistogramaTab.Title = 'Histograma';
            app.DiagramadeCajayBigotesTab = uitab(app.TabGroup);
            app.DiagramadeCajayBigotesTab.Title = 'Diagrama de Caja y Bigotes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizacionDatos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end